classdef PID_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIToolBar                      matlab.ui.container.Toolbar
        DesiredTemperatureEditField    matlab.ui.control.NumericEditField
        DesiredTemperatureEditFieldLabel  matlab.ui.control.Label
        KdEditField                    matlab.ui.control.NumericEditField
        KdEditFieldLabel               matlab.ui.control.Label
        KiEditField                    matlab.ui.control.NumericEditField
        KiEditFieldLabel               matlab.ui.control.Label
        KpEditField                    matlab.ui.control.NumericEditField
        KpEditField_2Label             matlab.ui.control.Label
        EnterEndPointandPIDValuePanel  matlab.ui.control.Label
        ButtonPanel                    matlab.ui.container.Panel
        RunButton                      matlab.ui.control.Button
        QUITButton                     matlab.ui.control.Button
        UIAxis                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        % Code that executes after component creation
        function startupFcn(app)
            app.RUN = 0;
            app.NPOINTS = 300;
            app.r = 0; %what is this value 
            app.Kp = 0;
            app.Ki = 0;
            app.Kd = 0;
            delete(instrfind);
            app.comport = serial('COM6','BaudRate',115200);
            fopen(app.comport);
        end
        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            app.RUN = 0;
            fclose(app.comport);
            clear app.comport
            delete(app)
            
        end
        % Value changed function: DesiredTemperatureEditField
        function DesiredTemperatureEditFieldValueChanged(app, event)
            value = app.DesiredTemperatureEditField.Value;
            app.r = value;
        end
        % Value changed function: KpEditField
        function KpEditFieldValueChanged(app, event)
            value = app.KpEditField.Value;
            app.Kp = value;
        end
        % Value changed function: KiEditField
        function KiEditFieldValueChanged(app, event)
            value = app.KiEditField.Value;
            app.Ki = value;
        end
        % Value changed function: KdEditField
        function KdEditFieldValueChanged(app, event)
            value = app.KdEditField.Value;
            app.Kd = value;
        end
        
          % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            i = 0;
            d = [];
            t = []; %time 
        if app.RUN == 0
            app.RUN = 1;
            % change the string on the button to STOP
            app.RUNButton.Text = 'STOP';
            while app.RUN == 1
                % send set point to the MCU
                fprintf(app.comport,'%s',app.r);
                % compute current time
                time = i*0.094;
                % fetch data as single 8-bit byte               
                   data = fread(app.comport,1,'uint8');
                % send PID values to the MCU
                fprintf(app.comport,'%s',app.Kp);
                fprintf(app.comport,'%s',app.Ki);
                fprintf(app.comport,'%s',app.Kd);
                if i<app.NPOINTS
                    i = i+1;
                    d(i) = data;
                    t(i) = time;
                else
                    i = i+1;
                    d(1:app.NPOINTS-1) = d(2:app.NPOINTS);
                    d(app.NPOINTS) = data;
                    t(1:app.NPOINTS-1) = t(2:app.NPOINTS);
                    t(app.NPOINTS) = time;
                end
                % plot the data on the screen
                plot(app.UIAxes,t,d)
                ylim(app.UIAxes,[0 100])
                % use drawnow to update the figure
                drawnow
            end
        else
            app.RUN = 0;
            % change the string on the button to RUN
            app.RUNButton.Text = 'RUN';
        end
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.ZoomIn, app.ZoomOut, app.uitoggletool3];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.UIFigure, 'off')
            rotate3d(app.UIFigure, 'off');
            pan(app.UIFigure, 'off');
            zoom(app.UIFigure,'off');
        end
        
        function results = func5(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PID_GUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to PID_GUI (see VARARGIN)
            
            % Choose default command line output for PID_GUI
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'PID_GUI';
            app.UIFigure.Tag = 'figure1';
            app.UIToolBar = uitoolbar(app.UIFigure);
            app.UIToolBar.Tag = 'uitoolbar1';
            app.UIAxis = uiaxes(app.UIFigure);
            title(app.UIAxis, {'Measured Temperature Vs. Time'; ''})
            xlabel(app.UIAxis, 'Time (s)')
            ylabel(app.UIAxis, {'Temperature (°C)'; ''})
            app.UIAxis.XLim = [0 30];
            app.UIAxis.YLim = [0 145];
            app.UIAxis.XTick = [0 5 10 15 20 25 30];
            app.UIAxis.YTick = [0 20 40 60 80 100 120 140];
            app.UIAxis.YTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'};
            app.UIAxis.Tag = 'axes1';
            app.ButtonPanel = uipanel(app.UIFigure);
            app.ButtonPanel.Title = 'Panel';
            app.ButtonPanel.Tag = 'uipanel1';
            app.QUITButton = uibutton(app.ButtonPanel, 'push');
            app.QUITButton.Tag = 'pushbutton3';
            app.QUITButton.Text = 'Quit';
            app.RunButton = uibutton(app.ButtonPanel, 'push');
            app.RunButton.Tag = 'pushbutton1';
            app.RunButton.Text = 'Run';
            app.EnterEndPointandPIDValuePanel = uilabel(app.UIFigure);
            app.EnterEndPointandPIDValuePanel.Tag = 'text5';
            app.EnterEndPointandPIDValuePanel.Text = 'Enter Set Point and PID Values';
            app.KpEditField_2Label = uilabel(app.UIFigure);
            app.KpEditField_2Label.Text = 'Kp';
            app.KpEditField = uieditfield(app.UIFigure, 'numeric');
            app.KiEditFieldLabel = uilabel(app.UIFigure);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.UIFigure, 'numeric');
            app.KdEditFieldLabel = uilabel(app.UIFigure);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredTemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredTemperatureEditFieldLabel.Text = 'Desired Temperature';
            app.DesiredTemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID_GUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)PID_GUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end